classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DotProductResultLabel        matlab.ui.control.Label
        AddNoiseandPlotButton        matlab.ui.control.Button
        CalculateConvolutionButton   matlab.ui.control.Button
        CalculateFFTButton           matlab.ui.control.Button
        CalculateDotProductButton    matlab.ui.control.Button
        CalculateSumButton           matlab.ui.control.Button
        GenerateSignalsButton        matlab.ui.control.Button
        Panel2                       matlab.ui.container.Panel
        Type2DropDown                matlab.ui.control.DropDown
        Type2DropDownLabel           matlab.ui.control.Label
        SamplingFrequency2EditField  matlab.ui.control.NumericEditField
        SamplingFrequency2EditFieldLabel  matlab.ui.control.Label
        Frequency2EditField          matlab.ui.control.NumericEditField
        Frequency2EditFieldLabel     matlab.ui.control.Label
        Amplitude2EditField          matlab.ui.control.NumericEditField
        Amplitude2EditFieldLabel     matlab.ui.control.Label
        Panel3                       matlab.ui.container.Panel
        StandartDeviationEditField   matlab.ui.control.NumericEditField
        StandartDeviationEditFieldLabel  matlab.ui.control.Label
        MeanEditField                matlab.ui.control.NumericEditField
        MeanEditFieldLabel           matlab.ui.control.Label
        Panel1                       matlab.ui.container.Panel
        Type1DropDown                matlab.ui.control.DropDown
        Type1DropDownLabel           matlab.ui.control.Label
        SamplingFrequency1EditField  matlab.ui.control.NumericEditField
        SamplingFrequency1EditFieldLabel  matlab.ui.control.Label
        Frequency1EditField          matlab.ui.control.NumericEditField
        Frequency1EditFieldLabel     matlab.ui.control.Label
        Amplitude1EditField          matlab.ui.control.NumericEditField
        Amplitude1EditFieldLabel     matlab.ui.control.Label
        UIAxes_9                     matlab.ui.control.UIAxes
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes_6                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_10                    matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_1                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Define properties for storing signal parameters
        amplitude1 double
        frequency1 double
        samplingFrequency1 double
        type1 char
        amplitude2 double
        frequency2 double
        samplingFrequency2 double
        type2 char
        noiseMean double
        noiseStd double
        signal1 double
        signal2 double
        combinedSignal double
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSignalsButton
        function GenerateSignalsButtonPushed(app, event)
            % Get parameters from the GUI
            app.amplitude1 = app.Amplitude1EditField.Value;
            app.frequency1 = app.Frequency1EditField.Value;
            app.samplingFrequency1 = app.SamplingFrequency1EditField.Value;
            app.type1 = app.Type1DropDown.Value;
        
            app.amplitude2 = app.Amplitude2EditField.Value;
            app.frequency2 = app.Frequency2EditField.Value;
            app.samplingFrequency2 = app.SamplingFrequency2EditField.Value;
            app.type2 = app.Type2DropDown.Value;
        
            % Create time vector
            t = (0:255) / app.samplingFrequency1;
        
            % Generate Signal 1
            if strcmp(app.type1, 'Sine')
                app.signal1 = app.amplitude1 * sin(2 * pi * app.frequency1 * t);
            else
                app.signal1 = app.amplitude1 * cos(2 * pi * app.frequency1 * t);
            end
            
            % Generate Signal 2
            t2 = (0:255) / app.samplingFrequency2;
            if strcmp(app.type2, 'Sine')
                app.signal2 = app.amplitude2 * sin(2 * pi * app.frequency2 * t2);
            else
                app.signal2 = app.amplitude2 * cos(2 * pi * app.frequency2 * t2);
            end
            
            % Plot signals
            plot(app.UIAxes_1, t, app.signal1);
            plot(app.UIAxes_2, t2, app.signal2);  
        end

        % Button pushed function: CalculateSumButton
        function CalculateSumButtonPushed(app, event)
            % Calculate the sum of both signals
            app.combinedSignal = app.signal1 + app.signal2;
            % Plot the result
            plot(app.UIAxes_3, app.combinedSignal);
        end

        % Button pushed function: CalculateDotProductButton
        function CalculateDotProductButtonPushed(app, event)
            % Calculate dot product
            dotProduct = dot(app.signal1, app.signal2);
            % Display result on the screen or as a text label
            app.DotProductResultLabel.Text = sprintf('Dot Product: %.2f', dotProduct);
        end

        % Button pushed function: CalculateFFTButton
        function CalculateFFTButtonPushed(app, event)
            % Compute FFT of both signals
            fft1 = fft(app.signal1);
            fft2 = fft(app.signal2);
            
            % Plot FFTs
            plot(app.UIAxes_5, abs(fft1));
            plot(app.UIAxes_6, abs(fft2));
        end

        % Button pushed function: CalculateConvolutionButton
        function CalculateConvolutionButtonPushed(app, event)
            % Perform convolution
            convSignal = conv(app.signal1, app.signal2);
            % Plot the convolution result
            plot(app.UIAxes_7, convSignal);
        end

        % Button pushed function: AddNoiseandPlotButton
        function AddNoiseandPlotButtonPushed(app, event)
            % Get Gaussian noise parameters
            app.noiseMean = app.MeanEditField.Value;
            app.noiseStd = app.StandartDeviationEditField.Value;
            
            % Generate noise
            noise = app.noiseMean + app.noiseStd * randn(size(app.signal1));
            
            % Calculate FFT of noise
            fftNoise = fft(noise);
            
            % Plot noise and its FFT
            plot(app.UIAxes_8, noise);
            plot(app.UIAxes_9, abs(fftNoise));
            
            % Add noise to combined signal and plot
            noisySignal = app.combinedSignal + noise;
            fftNoisySignal = fft(noisySignal);
            plot(app.UIAxes_10, abs(fftNoisySignal));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_1 = uiaxes(app.GridLayout);
            title(app.UIAxes_1, 'Signal 1 Plot')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Signal 2 Plot')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout);
            title(app.UIAxes_5, 'FFT of Signal 1')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_10 = uiaxes(app.GridLayout);
            title(app.UIAxes_10, 'Combined Signal and FFT Plot')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Sum of Signals Plot')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_6 = uiaxes(app.GridLayout);
            title(app.UIAxes_6, 'FFT of Signal 2')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_8 = uiaxes(app.GridLayout);
            title(app.UIAxes_8, 'Noise')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_7 = uiaxes(app.GridLayout);
            title(app.UIAxes_7, 'Convolution Result')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_9 = uiaxes(app.GridLayout);
            title(app.UIAxes_9, 'FFT of Noise')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.Panel1 = uipanel(app.GridLayout);
            app.Panel1.Title = 'Panel 1';
            app.Amplitude1EditFieldLabel = uilabel(app.Panel1);
            app.Amplitude1EditFieldLabel.Text = 'Amplitude1';
            app.Amplitude1EditField = uieditfield(app.Panel1, 'numeric');
            app.Frequency1EditFieldLabel = uilabel(app.Panel1);
            app.Frequency1EditFieldLabel.Text = 'Frequency1';
            app.Frequency1EditField = uieditfield(app.Panel1, 'numeric');
            app.SamplingFrequency1EditFieldLabel = uilabel(app.Panel1);
            app.SamplingFrequency1EditFieldLabel.Text = 'Sampling Frequency1';
            app.SamplingFrequency1EditField = uieditfield(app.Panel1, 'numeric');
            app.Type1DropDownLabel = uilabel(app.Panel1);
            app.Type1DropDownLabel.Text = 'Type1';
            app.Type1DropDown = uidropdown(app.Panel1);
            app.Type1DropDown.Items = {'Sine', 'Cosine'};
            app.Type1DropDown.Value = 'Sine';
            app.Panel3 = uipanel(app.GridLayout);
            app.Panel3.Title = 'Panel 3';
            app.MeanEditFieldLabel = uilabel(app.Panel3);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.Panel3, 'numeric');
            app.StandartDeviationEditFieldLabel = uilabel(app.Panel3);
            app.StandartDeviationEditFieldLabel.Text = 'Standart Deviation';
            app.StandartDeviationEditField = uieditfield(app.Panel3, 'numeric');
            app.Panel2 = uipanel(app.GridLayout);
            app.Panel2.Title = 'Panel 2';
            app.Amplitude2EditFieldLabel = uilabel(app.Panel2);
            app.Amplitude2EditFieldLabel.Text = 'Amplitude2';
            app.Amplitude2EditField = uieditfield(app.Panel2, 'numeric');
            app.Frequency2EditFieldLabel = uilabel(app.Panel2);
            app.Frequency2EditFieldLabel.Text = 'Frequency2';
            app.Frequency2EditField = uieditfield(app.Panel2, 'numeric');
            app.SamplingFrequency2EditFieldLabel = uilabel(app.Panel2);
            app.SamplingFrequency2EditFieldLabel.Text = 'Sampling Frequency2';
            app.SamplingFrequency2EditField = uieditfield(app.Panel2, 'numeric');
            app.Type2DropDownLabel = uilabel(app.Panel2);
            app.Type2DropDownLabel.Text = 'Type2';
            app.Type2DropDown = uidropdown(app.Panel2);
            app.Type2DropDown.Items = {'Sine', 'Cosine'};
            app.Type2DropDown.Value = 'Sine';
            app.GenerateSignalsButton = uibutton(app.GridLayout, 'push');
            app.GenerateSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalsButtonPushed, true);
            app.GenerateSignalsButton.Text = 'Generate Signals';
            app.CalculateSumButton = uibutton(app.GridLayout, 'push');
            app.CalculateSumButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSumButtonPushed, true);
            app.CalculateSumButton.Text = 'Calculate Sum';
            app.CalculateDotProductButton = uibutton(app.GridLayout, 'push');
            app.CalculateDotProductButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDotProductButtonPushed, true);
            app.CalculateDotProductButton.Text = 'Calculate Dot Product';
            app.CalculateFFTButton = uibutton(app.GridLayout, 'push');
            app.CalculateFFTButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFFTButtonPushed, true);
            app.CalculateFFTButton.Text = 'Calculate FFT';
            app.CalculateConvolutionButton = uibutton(app.GridLayout, 'push');
            app.CalculateConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateConvolutionButtonPushed, true);
            app.CalculateConvolutionButton.Text = 'Calculate Convolution';
            app.AddNoiseandPlotButton = uibutton(app.GridLayout, 'push');
            app.AddNoiseandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseandPlotButtonPushed, true);
            app.AddNoiseandPlotButton.Text = 'Add Noise and Plot';
            app.DotProductResultLabel = uilabel(app.GridLayout);
            app.DotProductResultLabel.Text = 'Dot Product:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end